classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable2                        matlab.ui.control.Table
        IloiteracjiLabel                matlab.ui.control.Label
        IloagentwLabel                  matlab.ui.control.Label
        OgraniczeniegrneLabel           matlab.ui.control.Label
        OgraniczeniedolneLabel          matlab.ui.control.Label
        LiczbazmiennychLabel            matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        maksymalizacjaButton            matlab.ui.control.RadioButton
        minimalizacjaButton             matlab.ui.control.RadioButton
        OptymalnywynikEditFieldLabel_2  matlab.ui.control.Label
        OptymalnywynikEditField         matlab.ui.control.NumericEditField
        OptymalnywynikEditFieldLabel    matlab.ui.control.Label
        ObliczButton                    matlab.ui.control.Button
        iterEditField                   matlab.ui.control.NumericEditField
        iterEditFieldLabel              matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        x_maxEditField                  matlab.ui.control.NumericEditField
        x_maxLabel                      matlab.ui.control.Label
        x_minEditField                  matlab.ui.control.NumericEditField
        x_minLabel                      matlab.ui.control.Label
        dimEditField                    matlab.ui.control.NumericEditField
        dimEditFieldLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            
            agents_num = app.NEditField.Value;
            iterations = app.iterEditField.Value;
            lb=app.x_minEditField.Value; 
            ub=app.x_maxEditField.Value;
            dim=app.dimEditField.Value;
            
            %cyferki do testow
            % agents_num = 30;
            % iterations = 1000;
            % lb=-600;
            % ub=600;
            % dim=100;

            [best_score, best_pos, convergence_plot, fun] = gray_wolf_opt( dim, lb, ub, agents_num, iterations);

            %dziala, nareszczie
            %
            % w nienawisci do Matlaba zostalem wychowany
            %w mym domu Matlab wiec bedzie jebany
            %bo ten jezyk jest kurwa niedojebany


            x = -10:0.1:10
            y = x;
            l = length(x)
            for i = 1:l
                for j=1:l
                    z(i,j) = fun([x(i), y(j)]);
                end
            end
            plot(app.UIAxes,1,2)
            surfc(app.UIAxes,x,y,z, 'LineStyle', 'none');
            %sukces znowu to kurestwo zadzialalo xD
  
            plot(app.UIAxes2,1,2);
            semilogy(app.UIAxes2,convergence_plot,'Color','r')
            
            %to co zakomentowane to mozna w razie potrzeby probowac recznie ustawic
            %chyba ustawilem dobrze ale zostawiam to czego pewny nie jestem
            %zakomentowane wrazie w jakby trzeba bylo poprawic zeby bylo
            %wiadomo co
            axis(app.UIAxes2,"tight")
            %box(on)
       
            legend(app.UIAxes2,'GWO')

            app.OptymalnywynikEditField.Value = best_score;
            app.UITable2.Data = best_pos


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Parameter space')
            xlabel(app.UIAxes, 'x_1')
            ylabel(app.UIAxes, 'x_2''')
            zlabel(app.UIAxes, 'Funkszjon'',''( x_1 , x_2 )')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Objective space')
            xlabel(app.UIAxes2, 'Iteration')
            ylabel(app.UIAxes2, 'Best score obtained so far')
            zlabel(app.UIAxes2, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Gray Wolf Opitimizer'};
            app.UITable.RowName = {};
            app.dimEditFieldLabel = uilabel(app.UIFigure);
            app.dimEditFieldLabel.Text = '(dim)';
            app.dimEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_minLabel = uilabel(app.UIFigure);
            app.x_minLabel.Text = '(x_min)';
            app.x_minEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_maxLabel = uilabel(app.UIFigure);
            app.x_maxLabel.Text = '(x_max)';
            app.x_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = '(N)';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.iterEditFieldLabel = uilabel(app.UIFigure);
            app.iterEditFieldLabel.Text = '(iter)';
            app.iterEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.OptymalnywynikEditFieldLabel = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel.Text = 'Optymalny wynik';
            app.OptymalnywynikEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptymalnywynikEditFieldLabel_2 = uilabel(app.UIFigure);
            app.OptymalnywynikEditFieldLabel_2.Text = 'Optymalne pozycje';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.minimalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.minimalizacjaButton.Text = 'minimalizacja';
            app.minimalizacjaButton.Value = true;
            app.maksymalizacjaButton = uiradiobutton(app.ButtonGroup);
            app.maksymalizacjaButton.Text = 'maksymalizacja';
            app.LiczbazmiennychLabel = uilabel(app.UIFigure);
            app.LiczbazmiennychLabel.Text = 'Liczba zmiennych';
            app.OgraniczeniedolneLabel = uilabel(app.UIFigure);
            app.OgraniczeniedolneLabel.Text = 'Ograniczenie dolne';
            app.OgraniczeniegrneLabel = uilabel(app.UIFigure);
            app.OgraniczeniegrneLabel.Text = 'Ograniczenie górne';
            app.IloagentwLabel = uilabel(app.UIFigure);
            app.IloagentwLabel.Text = 'Ilość agentów';
            app.IloiteracjiLabel = uilabel(app.UIFigure);
            app.IloiteracjiLabel.Text = 'Ilość iteracji';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'; 'Column 13'; 'Column 14'; 'Column 15'; 'Column 16'; 'Column 17'; 'Column 18'; 'Column 19'; 'Column 20'; 'Column 21'; 'Column 22'; 'Column 23'; 'Column 24'; 'Column 25'; 'Column 26'; 'Column 27'; 'Column 28'; 'Column 29'; 'Column 30'; 'Column 31'; 'Column 32'; 'Column 33'; 'Column 34'; 'Column 35'; 'Column 36'; 'Column 37'; 'Column 38'; 'Column 39'; 'Column 40'; 'Column 41'; 'Column 42'; 'Column 43'; 'Column 44'; 'Column 45'; 'Column 46'; 'Column 47'; 'Column 48'; 'Column 49'; 'Column 50'; 'Column 51'; 'Column 52'; 'Column 53'; 'Column 54'; 'Column 55'; 'Column 56'; 'Column 57'; 'Column 58'; 'Column 59'; 'Column 60'; 'Column 61'; 'Column 62'; 'Column 63'; 'Column 64'; 'Column 65'; 'Column 66'; 'Column 67'; 'Column 68'; 'Column 69'; 'Column 70'; 'Column 71'; 'Column 72'; 'Column 73'; 'Column 74'; 'Column 75'; 'Column 76'; 'Column 77'; 'Column 78'; 'Column 79'; 'Column 80'; 'Column 81'; 'Column 82'; 'Column 83'; 'Column 84'; 'Column 85'; 'Column 86'; 'Column 87'; 'Column 88'; 'Column 89'; 'Column 90'; 'Column 91'; 'Column 92'; 'Column 93'; 'Column 94'; 'Column 95'; 'Column 96'; 'Column 97'; 'Column 98'; 'Column 99'; 'Column 100'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end